classdef BarnesHutApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        PlayGeneratedVideoButton     matlab.ui.control.Button
        ResultsTextArea              matlab.ui.control.TextArea
        ResultsTextAreaLabel         matlab.ui.control.Label
        SimulateButton               matlab.ui.control.Button
        SimulationTimeSpinner        matlab.ui.control.Spinner
        SimulationTimeSpinnerLabel   matlab.ui.control.Label
        BlackHoleSizeGalaxy2Spinner  matlab.ui.control.Spinner
        BlackHoleSizeGalaxy2SpinnerLabel  matlab.ui.control.Label
        BlackHoleSizeGalaxy1Spinner  matlab.ui.control.Spinner
        BlackHoleSizeGalaxy1SpinnerLabel  matlab.ui.control.Label
        BackendButtonGroup           matlab.ui.container.ButtonGroup
        MATLABButton                 matlab.ui.control.RadioButton
        SimulinkStateFlowButton      matlab.ui.control.RadioButton
        CMEXButton                   matlab.ui.control.RadioButton
        PlaceHolderCheckBox          matlab.ui.control.CheckBox
        ViewBarnesHutboxesCheckBox   matlab.ui.control.CheckBox
        ParticlecountGalaxy2Spinner  matlab.ui.control.Spinner
        ParticlecountGalaxy2SpinnerLabel  matlab.ui.control.Label
        ParticlecountGalaxy1Spinner  matlab.ui.control.Spinner
        ParticlecountGalaxy1SpinnerLabel  matlab.ui.control.Label
        SimulationTypeButtonGroup    matlab.ui.container.ButtonGroup
        OneGalaxyButton              matlab.ui.control.RadioButton
        TwoGalaxiesButton            matlab.ui.control.RadioButton
        Random1galaxyButton          matlab.ui.control.RadioButton
        BarnesHutSimulationLabel     matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            % Disable UI controls during simulation
            app.SimulateButton.Enable = 'off';
            app.PlayGeneratedVideoButton.Enable = 'off';
            drawnow;
        
            % Gather parameters from UI
            args = struct();
            args.SimType = app.SimulationTypeButtonGroup.SelectedObject.Text;
            args.Galaxy1Particles = app.ParticlecountGalaxy1Spinner.Value;
            args.Galaxy2Particles = app.ParticlecountGalaxy2Spinner.Value;
            args.BlackHole1 = app.BlackHoleSizeGalaxy1Spinner.Value;
            args.BlackHole2 = app.BlackHoleSizeGalaxy2Spinner.Value;
            args.SimulationTime = app.SimulationTimeSpinner.Value;
            args.Backend = app.BackendButtonGroup.SelectedObject.Text;
            args.ViewBoxes = app.ViewBarnesHutboxesCheckBox.Value;
        
            % Start timer
            tic;
            try
                videoPath = main(args);
                elapsedTime = toc;
        
                % Show results in text area
                resultStr = sprintf("Simulation Complete!\nTime Taken: %.2f sec\nBackend: %s\nSimType: %s\nGalaxy1: %d stars, BH Size: %d\nGalaxy2: %d stars, BH Size: %d", ...
                    elapsedTime, args.Backend, args.SimType, ...
                    args.Galaxy1Particles, args.BlackHole1, ...
                    args.Galaxy2Particles, args.BlackHole2);
                app.ResultsTextArea.Value = resultStr;
        
                % Store video path in app
                app.PlayGeneratedVideoButton.UserData = videoPath;
                app.PlayGeneratedVideoButton.Enable = 'on';
            catch ME
                app.ResultsTextArea.Value = sprintf("❌ Simulation failed:\n%s", ME.message);
            end
        
            % Re-enable simulate button
            app.SimulateButton.Enable = 'on';
            % % Disable everything and Run Code here
            % % Disable simulate button
            % app.SimulateButton.Enable = 'off';
            % app.PlayGeneratedVideoButton.Enable = 'off';
            % drawnow;
            % 
            % % Record start time
            % tStart = tic;
            % 
            % % Get simulation parameters
            % simType = app.SimulationTypeButtonGroup.SelectedObject.Text;
            % backend = app.BackendButtonGroup.SelectedObject.Text;
            % simTime = app.SimulationTimeSpinner.Value;
            % 
            % % Default values
            % p1 = 0; p2 = 0; bh1 = 0; bh2 = 0;
            % 
            % if app.ParticlecountGalaxy1Spinner.Enable == "on"
            %     p1 = app.ParticlecountGalaxy1Spinner.Value;
            %     bh1 = app.BlackHoleSizeGalaxy1Spinner.Value;
            % end
            % if app.ParticlecountGalaxy2Spinner.Enable == "on"
            %     p2 = app.ParticlecountGalaxy2Spinner.Value;
            %     bh2 = app.BlackHoleSizeGalaxy2Spinner.Value;
            % end
            % 
            % % Prepare argument struct
            % args = struct('SimType', simType, ...
            %               'Galaxy1Particles', p1, ...
            %               'Galaxy2Particles', p2, ...
            %               'BlackHole1', bh1, ...
            %               'BlackHole2', bh2, ...
            %               'SimulationTime', simTime, ...
            %               'Backend', backend, ...
            %               'ViewBoxes', app.ViewBarnesHutboxesCheckBox.Value);
            % 
            % % Call the appropriate simulation based on backend
            % try
            %     outputFile = runBarnesHutSimulation(args);  % <-- your external function
            %     elapsedTime = toc(tStart);
            % 
            %     % Update results
            %     resultStr = sprintf(['Simulation completed in %.2f seconds.\n', ...
            %         'Type: %s | Backend: %s\nGalaxy 1: %d particles, BH Size: %d\n' ...
            %         'Galaxy 2: %d particles, BH Size: %d\nTime: %d frames'], ...
            %         elapsedTime, simType, backend, p1, bh1, p2, bh2, simTime);
            % 
            %     app.ResultsTextArea.Value = resultStr;
            % 
            %     % Store output path
            %     app.PlayGeneratedVideoButton.UserData = outputFile;
            %     app.PlayGeneratedVideoButton.Enable = 'on';
            % catch ME
            %     app.ResultsTextArea.Value = ['Error: ', ME.message];
            % end
            % 
            % % Re-enable simulate button
            % app.SimulateButton.Enable = 'on';
            % 
            % % Once run, enable PlayGeneratedVideoButton
        end

        % Button pushed function: PlayGeneratedVideoButton
        function PlayGeneratedVideoButtonPushed(app, event)
            % Run recently generated file
            if isfield(app.PlayGeneratedVideoButton.UserData, 'VideoPath')
            videoPath = app.PlayGeneratedVideoButton.UserData.VideoPath;
        else
            videoPath = app.PlayGeneratedVideoButton.UserData;
        end
        if isfile(videoPath)
            winopen(videoPath);  % On Windows
            % mac: system(['open "', videoPath, '"']);
            % linux: system(['xdg-open "', videoPath, '"']);
        else
            uialert(app.UIFigure, 'Video file not found.', 'Error');
        end
        end

        % Selection changed function: SimulationTypeButtonGroup
        function SimulationTypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.SimulationTypeButtonGroup.SelectedObject;
    
            % Enable everything initially
            app.ParticlecountGalaxy1Spinner.Enable = 'on';
            app.ParticlecountGalaxy2Spinner.Enable = 'on';
            app.BlackHoleSizeGalaxy1Spinner.Enable = 'on';
            app.BlackHoleSizeGalaxy2Spinner.Enable = 'on';
            
            % Switch logic
            if selectedButton == app.Random1galaxyButton
                app.ParticlecountGalaxy1Spinner.Enable = 'off';
                app.ParticlecountGalaxy2Spinner.Enable = 'off';
                app.BlackHoleSizeGalaxy1Spinner.Enable = 'off';
                app.BlackHoleSizeGalaxy2Spinner.Enable = 'off';
            elseif selectedButton == app.OneGalaxyButton
                app.ParticlecountGalaxy2Spinner.Enable = 'off';
                app.BlackHoleSizeGalaxy2Spinner.Enable = 'off';
            elseif selectedButton == app.TwoGalaxiesButton
                % Keep all enabled
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BarnesHutSimulationLabel = uilabel(app.UIFigure);
            app.BarnesHutSimulationLabel.Text = 'Barnes-Hut Simulation';
            app.SimulationTypeButtonGroup = uibuttongroup(app.UIFigure);
            app.SimulationTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SimulationTypeButtonGroupSelectionChanged, true);
            app.SimulationTypeButtonGroup.Title = 'Simulation Type';
            app.Random1galaxyButton = uiradiobutton(app.SimulationTypeButtonGroup);
            app.Random1galaxyButton.Text = 'Random (1 galaxy)';
            app.Random1galaxyButton.Value = true;
            app.TwoGalaxiesButton = uiradiobutton(app.SimulationTypeButtonGroup);
            app.TwoGalaxiesButton.Text = 'Two Galaxies';
            app.OneGalaxyButton = uiradiobutton(app.SimulationTypeButtonGroup);
            app.OneGalaxyButton.Text = 'One Galaxy';
            app.ParticlecountGalaxy1SpinnerLabel = uilabel(app.UIFigure);
            app.ParticlecountGalaxy1SpinnerLabel.Text = 'Particle count (Galaxy 1)';
            app.ParticlecountGalaxy1Spinner = uispinner(app.UIFigure);
            app.ParticlecountGalaxy1Spinner.Value = 100;
            app.ParticlecountGalaxy2SpinnerLabel = uilabel(app.UIFigure);
            app.ParticlecountGalaxy2SpinnerLabel.Text = 'Particle count (Galaxy 2)';
            app.ParticlecountGalaxy2Spinner = uispinner(app.UIFigure);
            app.ViewBarnesHutboxesCheckBox = uicheckbox(app.UIFigure);
            app.ViewBarnesHutboxesCheckBox.Text = 'View Barnes-Hut boxes';
            app.PlaceHolderCheckBox = uicheckbox(app.UIFigure);
            app.PlaceHolderCheckBox.Text = 'PlaceHolder';
            app.BackendButtonGroup = uibuttongroup(app.UIFigure);
            app.BackendButtonGroup.Title = 'Backend';
            app.CMEXButton = uiradiobutton(app.BackendButtonGroup);
            app.CMEXButton.Text = 'C++ MEX';
            app.CMEXButton.Value = true;
            app.SimulinkStateFlowButton = uiradiobutton(app.BackendButtonGroup);
            app.SimulinkStateFlowButton.Text = 'Simulink StateFlow';
            app.MATLABButton = uiradiobutton(app.BackendButtonGroup);
            app.MATLABButton.Text = 'MATLAB';
            app.BlackHoleSizeGalaxy1SpinnerLabel = uilabel(app.UIFigure);
            app.BlackHoleSizeGalaxy1SpinnerLabel.Text = 'Black Hole Size (Galaxy 1)';
            app.BlackHoleSizeGalaxy1Spinner = uispinner(app.UIFigure);
            app.BlackHoleSizeGalaxy2SpinnerLabel = uilabel(app.UIFigure);
            app.BlackHoleSizeGalaxy2SpinnerLabel.Text = 'Black Hole Size (Galaxy 2)';
            app.BlackHoleSizeGalaxy2Spinner = uispinner(app.UIFigure);
            app.SimulationTimeSpinnerLabel = uilabel(app.UIFigure);
            app.SimulationTimeSpinnerLabel.Text = 'Simulation Time';
            app.SimulationTimeSpinner = uispinner(app.UIFigure);
            app.SimulateButton = uibutton(app.UIFigure, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.ResultsTextAreaLabel = uilabel(app.UIFigure);
            app.ResultsTextAreaLabel.Text = 'Results';
            app.ResultsTextArea = uitextarea(app.UIFigure);
            app.PlayGeneratedVideoButton = uibutton(app.UIFigure, 'push');
            app.PlayGeneratedVideoButton.ButtonPushedFcn = createCallbackFcn(app, @PlayGeneratedVideoButtonPushed, true);
            app.PlayGeneratedVideoButton.Text = 'Play Generated Video';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BarnesHutApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end